classdef cross_polarization0708 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        parameter1Panel            matlab.ui.container.Panel
        g0EditFieldLabel           matlab.ui.control.Label
        g0EditField                matlab.ui.control.NumericEditField
        AEditFieldLabel            matlab.ui.control.Label
        AEditField                 matlab.ui.control.NumericEditField
        cEditFieldLabel            matlab.ui.control.Label
        cEditField                 matlab.ui.control.NumericEditField
        fEditFieldLabel            matlab.ui.control.Label
        fEditField                 matlab.ui.control.NumericEditField
        DEditFieldLabel            matlab.ui.control.Label
        DEditField                 matlab.ui.control.NumericEditField
        dEditFieldLabel            matlab.ui.control.Label
        dEditField                 matlab.ui.control.NumericEditField
        xiEditFieldLabel           matlab.ui.control.Label
        xiEditField                matlab.ui.control.NumericEditField
        constantLabel              matlab.ui.control.Label
        amplitudeLabel             matlab.ui.control.Label
        msLabel                    matlab.ui.control.Label
        HzLabel                    matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        mLabel_2                   matlab.ui.control.Label
        fixedphaseerrordegLabel_2  matlab.ui.control.Label
        parameter2Panel            matlab.ui.container.Panel
        lambdaEditFieldLabel       matlab.ui.control.Label
        lambdaEditField            matlab.ui.control.NumericEditField
        theta3EditFieldLabel       matlab.ui.control.Label
        theta3EditField            matlab.ui.control.NumericEditField
        theta_sEditFieldLabel      matlab.ui.control.Label
        theta_sEditField           matlab.ui.control.NumericEditField
        kEditFieldLabel            matlab.ui.control.Label
        kEditField                 matlab.ui.control.NumericEditField
        theta_tEditFieldLabel      matlab.ui.control.Label
        theta_tEditField           matlab.ui.control.NumericEditField
        kmEditFieldLabel           matlab.ui.control.Label
        kmEditField                matlab.ui.control.NumericEditField
        mLabel                     matlab.ui.control.Label
        constantLabel_2            matlab.ui.control.Label
        constantLabel_3            matlab.ui.control.Label
        constantLabel_4            matlab.ui.control.Label
        constantLabel_5            matlab.ui.control.Label
        constantLabel_6            matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes3                    matlab.ui.control.UIAxes
        UIAxes4                    matlab.ui.control.UIAxes
        noiseAWGNPanel             matlab.ui.container.Panel
        muEditFieldLabel           matlab.ui.control.Label
        muEditField                matlab.ui.control.NumericEditField
        sigmaEditFieldLabel        matlab.ui.control.Label
        sigmaEditField             matlab.ui.control.NumericEditField
        outputPanel                matlab.ui.container.Panel
        errorrateEditFieldLabel    matlab.ui.control.Label
        errorrateEditField         matlab.ui.control.NumericEditField
        Label_2                    matlab.ui.control.Label
        StartButton                matlab.ui.control.Button
        ResetButton                matlab.ui.control.Button
        delta_timeEditFieldLabel   matlab.ui.control.Label
        delta_timeEditField        matlab.ui.control.NumericEditField
        max_timeEditFieldLabel     matlab.ui.control.Label
        max_timeEditField          matlab.ui.control.NumericEditField
        sLabel                     matlab.ui.control.Label
        sLabel_2                   matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

g0 = 1;                                     % constant
A = 1;                                      % amplitude of signal
c = 3*10^8;                                 % [m/s]
f = 300*10^6;                               % [Hz]           
lambda = c/f;                               % wavelength(파장) [m]
D = 0.8;                                    % aperture diameter(유효 안테나 직경)
theta3 = lambda/D;                          % constant
theta_s = theta3/3;                         % constant
k = 4*(theta_s/theta3)*log2(2);             % constant
d = 1;                                      % distance of monopulse radar [m]
xi = 1;                                     % fixed phase error [deg]
theta_t = -asin(xi/(k*d));                  % chapter7, 202p (7.48) Equ.
km = 2*sqrt(2)*log2(2)*theta_s/theta3;      % anttena constant

% azi_angle과 elv_angle 임의로 설정
delta_time = 1;
max_time = 20;
for i = 1 : delta_time : max_time
    azi_angle(i) = i*0.2;
    elv_angle(i) = -i*0.2;
end
% -------------------------------------------
app.g0EditField.Value = g0;
app.AEditField.Value = A;
app.cEditField.Value = c;
app.fEditField.Value = f;
app.lambdaEditField.Value = lambda;
app.DEditField.Value = lambda;
app.theta3EditField.Value = theta3;
app.theta_sEditField.Value = theta_s;
app.kEditField.Value = k;
app.dEditField.Value = d;
app.xiEditField.Value = xi;
app.theta_tEditField.Value = theta_t;
app.kmEditField.Value = km;
app.delta_timeEditField.Value = delta_time;
app.max_timeEditField.Value = max_time;
%--------------------------------------------
% AWGN channel
mu = 0; sigma = 0;
noise = mu + sigma*randn(length(azi_angle),1)';
%--------------------------------------------
app.muEditField.Value = mu;
app.sigmaEditField.Value = noise;
%--------------------------------------------
% function G4를 이용하여 beam의 gain 계산
angle_length = length(azi_angle);
calculate_gain = Fn_AnttenaVoltagePattern4(g0, km, theta3, azi_angle, elv_angle);
gain = reshape(calculate_gain,[angle_length,4]);

% 추정된 theta_t_azi와 theta_t_elv 계산
% (Fig. 7.30), delta_az = 1/2[(a+d)-(b+c)], delta_el = 1/2[(a+b)-(c+d)]
for i = 1 : delta_time : max_time
    delta_theta_t_az(i) = noise(i) + (1/2)*((gain(i,1) + gain(i,4)) - (gain(i,2) + gain(i,3)));
    delta_theta_t_el(i) = noise(i) + (1/2)*((gain(i,1) + gain(i,2)) - (gain(i,3) + gain(i,4)));
    estimation_theta_t_az = delta_theta_t_az*theta3/(2*g0*km*A);   % estimation of theta_t_azi
    estimation_theta_t_el = delta_theta_t_el*theta3/(2*g0*km*A);   % estimation of theta_t_elv
end

% azi_angle과 elv_angle 확인
azi_angle;
elv_angle;

% % 임의로 설정한 azi_angle과 elv_angle을 plot에 표시
% app.UIAxes(plot(azi_angle, elv_angle, 'color', 'b', 'Marker', 'o','LineWidth', 1));
% axis('square', [-max_time+10, max_time-10, -max_time+10, max_time-10]);
% ax = gca;
% ax.XAxisLocation = 'origin';
% ax.YAxisLocation = 'origin';
% title("임의로 설정한 azi_ angle과 elv_ angle");
% xlabel('azimuth');
% ylabel('elevation');

% 추정된 azi, elv를 추가로 plot에 표시
app.UIAxes(plot(azi_angle, elv_angle, 'color', 'b', 'Marker', 'o', 'LineWidth', 1));
axis('square', [-max_time+10, max_time-10, -max_time+10, max_time-10]);
ax = gca;
ax.XAxisLocation = 'origin';
ax.YAxisLocation = 'origin';
title("추정된 azi_ angle과 elv_ angle을 plot에 표시");
xlabel('azimuth');
ylabel('elevation');
hold on
app.UIAxes(plot(estimation_theta_t_az, estimation_theta_t_el, 'color', 'r', 'Marker','o', 'LineStyle', 'none'));
legend('임의의 azi 및 elv angle','no jamming azi 및 elv angle');
hold off

% RMSE
for i = 1 : delta_time : max_time
    RMSE_azi(i) = sqrt(mean((estimation_theta_t_az(i) - azi_angle(i)).^2));
    RMSE_elv(i) = sqrt(mean((estimation_theta_t_el(i) - elv_angle(i)).^2));
end
app.UIAxes2(plot(RMSE_azi, 'color', 'r', 'Marker','o', 'LineStyle', 'none'));
title('RMSE azimuth');
xlabel('time [s]');
ylabel('RMSE');
legend('No Jamming angle (azi)');
axis([0 max_time+1 0 max_time+10]);
% RMSE_azi;

app.UIAxes2(plot(RMSE_elv, 'color', 'r', 'Marker','o', 'LineStyle', 'none'));
title('RMSE elevation');
xlabel('time [s]');
ylabel('RMSE');
legend('No Jamming angle (elv)');
axis([0 max_time+1 0 max_time+10]);
% RMSE_elv;
% ----------------------------------------------------------------
% --------------------------- jamming ----------------------------
% ----------------------------------------------------------------

calculate_polar_gain = Fn_AnttenaVoltagePattern5(g0, km, theta3, azi_angle, elv_angle);
polar_gain = reshape(calculate_polar_gain,[angle_length,4]);

for i = 1 : delta_time : max_time
    polar_delta_theta_t_az(i) = noise(i) + (1/2)*((polar_gain(i,2) + polar_gain(i,3)) - (polar_gain(i,1) + polar_gain(i,4)));
    polar_delta_theta_t_el(i) = noise(i) + (1/2)*((polar_gain(i,3) + polar_gain(i,4)) - (polar_gain(i,1) + polar_gain(i,2)));
    polar_estimation_theta_t_az = polar_delta_theta_t_az*theta3/(2*g0*km*A);   % estimation of polar_theta_t_azi
    polar_estimation_theta_t_el = polar_delta_theta_t_el*theta3/(2*g0*km*A);   % estimation of polar_theta_t_elv
end

% 추정된 azi, elv를 추가로 plot에 표시
app.UIAxes3(plot(azi_angle, elv_angle, 'color', 'b', 'Marker', 'o', 'LineWidth', 1));
axis('square', [-max_time+10, max_time-10, -max_time+10, max_time-10]);
ax = gca;
ax.XAxisLocation = 'origin';
ax.YAxisLocation = 'origin';
title("추정된 azi_ angle과 elv_ angle을 plot에 표시");
xlabel('azimuth');
ylabel('elevation');
hold on
app.UIAxes3(plot(polar_estimation_theta_t_az, polar_estimation_theta_t_el, 'color', 'r', 'Marker','o', 'LineStyle', 'none'));
legend('임의의 azi 및 elv angle','jamming 적용한 azi 및 elv angle');
hold off

% RMSE
for i = 1 : delta_time : max_time
    polar_RMSE_azi(i) = sqrt(mean((polar_estimation_theta_t_az(i) - azi_angle(i)).^2));
    polar_RMSE_elv(i) = sqrt(mean((polar_estimation_theta_t_el(i) - elv_angle(i)).^2));
end
plot(polar_RMSE_azi, 'color', 'r', 'Marker','o', 'LineStyle', 'none');
title('Cross Polarization RMSE azimuth');
xlabel('time [s]');
ylabel('RMSE');
legend('Cross Polarization Jamming angle (azi)');
axis([0 max_time+1 0 max_time+10]);
polar_RMSE_azi;

plot(polar_RMSE_elv, 'color', 'r', 'Marker','o', 'LineStyle', 'none');
title('Cross Polarization RMSE elevation');
xlabel('time [s]');
ylabel('RMSE');
legend('Cross Polarization Jamming angle (elv)');
axis([0 max_time+1 0 max_time+10]);
polar_RMSE_elv;

% --------------------graph 겹쳐 그리기-------------------
plot(RMSE_azi, 'color', 'b', 'Marker','o', 'LineStyle', 'none');
hold on;
plot(polar_RMSE_azi, 'color', 'r', 'Marker','o', 'LineStyle', 'none');
title('RMSE azimuth');
xlabel('time [s]');
ylabel('RMSE');
legend('No Jamming angle (azi)','Cross Polarization Jamming angle (azi)');
axis([0 max_time+1 0 max_time+10]);
hold off;

plot(RMSE_azi, 'color', 'b', 'Marker','o', 'LineStyle', 'none');
hold on;
plot(polar_RMSE_elv, 'color', 'r', 'Marker','o', 'LineStyle', 'none');
title('RMSE elevation');
xlabel('time [s]');
ylabel('RMSE');
legend('No Jamming angle (elv)','Cross Polarization Jamming angle (elv)');
axis([0 max_time+1 0 max_time+10]);
hold off;

% ----------------------function-------------------------
function G3 = Fn_AnttenaVoltagePattern3(g0, azi_angle, elv_angle, theta3, theta_s, k1, theta_t)

g1 = g0*exp(-k1*((theta_s/theta3) - (theta_t/theta3)).^2 * (azi_angle + elv_angle)/theta3);
g2 = g0*exp(-k1*((theta_s/theta3) + (theta_t/theta3)).^2 * (azi_angle - elv_angle)/theta3);
g3 = g0*exp(-k1*((theta_s/theta3) - (theta_t/theta3)).^2 * (-azi_angle - elv_angle)/theta3);
g4 = g0*exp(-k1*((theta_s/theta3) + (theta_t/theta3)).^2 * (-azi_angle + elv_angle)/theta3);

G3 = [g1, g2, g3, g4];
end

function G4 = Fn_AnttenaVoltagePattern4(g0, km, theta3, azi_angle, elv_angle)

g1 = g0*(1 + km * ((azi_angle + elv_angle)/theta3));
g2 = g0*(1 + km * ((elv_angle - azi_angle)/theta3));
g3 = g0*(1 - km * ((elv_angle + azi_angle)/theta3));
g4 = g0*(1 + km * ((azi_angle - elv_angle)/theta3));

G4 = [g1, g2, g3, g4];
end

function G5 = Fn_AnttenaVoltagePattern5(g0, km, theta3, azi_angle, elv_angle)
kp_1theta = 1;  kp_2theta = -1;  kp_3theta = -1;  kp_4theta = 1;
kp_1psi = 1;  kp_2psi = 1;  kp_3psi = -1;  kp_4psi = -1;

g1 = g0*(1 + km * ((azi_angle + elv_angle)/theta3));
g2 = g0*(1 + km * ((elv_angle - azi_angle)/theta3));
g3 = g0*(1 - km * ((elv_angle + azi_angle)/theta3));
g4 = g0*(1 + km * ((azi_angle - elv_angle)/theta3));

g_p1 = -g1.*(1 + kp_1theta*elv_angle + kp_1psi*azi_angle);
g_p2 = g2.*(1 + kp_2theta*elv_angle - kp_2psi*azi_angle);
g_p3 = -g3.*(1 - kp_3theta*elv_angle - kp_3psi*azi_angle);
g_p4 = g4.*(1 - kp_4theta*elv_angle + kp_4psi*azi_angle);

G5 = [g_p1, g_p2, g_p3, g_p4];
end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.parameter1Panel = uipanel(app.UIFigure);
            app.parameter1Panel.Title = 'parameter1';
            app.g0EditFieldLabel = uilabel(app.parameter1Panel);
            app.g0EditFieldLabel.Text = 'g0';
            app.g0EditField = uieditfield(app.parameter1Panel, 'numeric');
            app.g0EditField.Value = 1;
            app.AEditFieldLabel = uilabel(app.parameter1Panel);
            app.AEditFieldLabel.Text = 'A';
            app.AEditField = uieditfield(app.parameter1Panel, 'numeric');
            app.AEditField.Value = 1;
            app.cEditFieldLabel = uilabel(app.parameter1Panel);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.parameter1Panel, 'numeric');
            app.cEditField.Value = 3;
            app.fEditFieldLabel = uilabel(app.parameter1Panel);
            app.fEditFieldLabel.Text = 'f';
            app.fEditField = uieditfield(app.parameter1Panel, 'numeric');
            app.DEditFieldLabel = uilabel(app.parameter1Panel);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.parameter1Panel, 'numeric');
            app.DEditField.Value = 0.8;
            app.dEditFieldLabel = uilabel(app.parameter1Panel);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.parameter1Panel, 'numeric');
            app.dEditField.Value = 1;
            app.xiEditFieldLabel = uilabel(app.parameter1Panel);
            app.xiEditFieldLabel.Text = 'xi';
            app.xiEditField = uieditfield(app.parameter1Panel, 'numeric');
            app.xiEditField.Value = 1;
            app.constantLabel = uilabel(app.parameter1Panel);
            app.constantLabel.Text = 'constant';
            app.amplitudeLabel = uilabel(app.parameter1Panel);
            app.amplitudeLabel.Text = '[amplitude]';
            app.msLabel = uilabel(app.parameter1Panel);
            app.msLabel.Text = '[m/s]';
            app.HzLabel = uilabel(app.parameter1Panel);
            app.HzLabel.Text = '[Hz]';
            app.Label = uilabel(app.parameter1Panel);
            app.Label.Text = '유효안테나직경';
            app.mLabel_2 = uilabel(app.parameter1Panel);
            app.mLabel_2.Text = '안테나 사이 거리 [m]';
            app.fixedphaseerrordegLabel_2 = uilabel(app.parameter1Panel);
            app.fixedphaseerrordegLabel_2.Text = 'fixed phase error [deg]';
            app.parameter2Panel = uipanel(app.UIFigure);
            app.parameter2Panel.Title = 'parameter2';
            app.lambdaEditFieldLabel = uilabel(app.parameter2Panel);
            app.lambdaEditFieldLabel.Text = 'lambda';
            app.lambdaEditField = uieditfield(app.parameter2Panel, 'numeric');
            app.theta3EditFieldLabel = uilabel(app.parameter2Panel);
            app.theta3EditFieldLabel.Text = 'theta3';
            app.theta3EditField = uieditfield(app.parameter2Panel, 'numeric');
            app.theta_sEditFieldLabel = uilabel(app.parameter2Panel);
            app.theta_sEditFieldLabel.Text = 'theta_s';
            app.theta_sEditField = uieditfield(app.parameter2Panel, 'numeric');
            app.kEditFieldLabel = uilabel(app.parameter2Panel);
            app.kEditFieldLabel.Text = 'k';
            app.kEditField = uieditfield(app.parameter2Panel, 'numeric');
            app.theta_tEditFieldLabel = uilabel(app.parameter2Panel);
            app.theta_tEditFieldLabel.Text = 'theta_t';
            app.theta_tEditField = uieditfield(app.parameter2Panel, 'numeric');
            app.kmEditFieldLabel = uilabel(app.parameter2Panel);
            app.kmEditFieldLabel.Text = 'km';
            app.kmEditField = uieditfield(app.parameter2Panel, 'numeric');
            app.mLabel = uilabel(app.parameter2Panel);
            app.mLabel.Text = '파장 [m]';
            app.constantLabel_2 = uilabel(app.parameter2Panel);
            app.constantLabel_2.Text = 'constant';
            app.constantLabel_3 = uilabel(app.parameter2Panel);
            app.constantLabel_3.Text = 'constant';
            app.constantLabel_4 = uilabel(app.parameter2Panel);
            app.constantLabel_4.Text = 'constant';
            app.constantLabel_5 = uilabel(app.parameter2Panel);
            app.constantLabel_5.Text = 'constant';
            app.constantLabel_6 = uilabel(app.parameter2Panel);
            app.constantLabel_6.Text = 'constant';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '임의의 azi 및 elv angle과 추정 angle (no jamming)')
            xlabel(app.UIAxes, 'azimuth')
            ylabel(app.UIAxes, 'elevation')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'RMSE (no jamming)')
            xlabel(app.UIAxes2, 'time [s]')
            ylabel(app.UIAxes2, 'RMSE')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, '임의의 azi 및 elv angle 추정 angle (jamming)')
            xlabel(app.UIAxes3, 'azimuth')
            ylabel(app.UIAxes3, 'elevation')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'RMSE (jamming)')
            xlabel(app.UIAxes4, 'time [s]')
            ylabel(app.UIAxes4, 'RMSE')
            app.noiseAWGNPanel = uipanel(app.UIFigure);
            app.noiseAWGNPanel.Title = 'noise(AWGN)';
            app.muEditFieldLabel = uilabel(app.noiseAWGNPanel);
            app.muEditFieldLabel.Text = 'mu';
            app.muEditField = uieditfield(app.noiseAWGNPanel, 'numeric');
            app.sigmaEditFieldLabel = uilabel(app.noiseAWGNPanel);
            app.sigmaEditFieldLabel.Text = 'sigma';
            app.sigmaEditField = uieditfield(app.noiseAWGNPanel, 'numeric');
            app.outputPanel = uipanel(app.UIFigure);
            app.outputPanel.Title = 'output';
            app.errorrateEditFieldLabel = uilabel(app.outputPanel);
            app.errorrateEditFieldLabel.Text = 'error rate';
            app.errorrateEditField = uieditfield(app.outputPanel, 'numeric');
            app.Label_2 = uilabel(app.outputPanel);
            app.Label_2.Text = '[%]';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.delta_timeEditFieldLabel = uilabel(app.UIFigure);
            app.delta_timeEditFieldLabel.Text = 'delta_time';
            app.delta_timeEditField = uieditfield(app.UIFigure, 'numeric');
            app.delta_timeEditField.Value = 1;
            app.max_timeEditFieldLabel = uilabel(app.UIFigure);
            app.max_timeEditFieldLabel.Text = 'max_time';
            app.max_timeEditField = uieditfield(app.UIFigure, 'numeric');
            app.max_timeEditField.Value = 20;
            app.sLabel = uilabel(app.UIFigure);
            app.sLabel.Text = '[s]';
            app.sLabel_2 = uilabel(app.UIFigure);
            app.sLabel_2.Text = '[s]';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cross_polarization0708

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end